classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        XAxisDropdown          matlab.ui.control.DropDown
        YAxisLabel_2           matlab.ui.control.Label
        YAxisDropdown          matlab.ui.control.DropDown
        YAxisLabel             matlab.ui.control.Label
        ToggleSwitch           matlab.ui.control.Switch
        NextButton             matlab.ui.control.Button
        PreviousButton         matlab.ui.control.Button
        ModeSwitch             matlab.ui.control.Switch
        SimulationProgress     simulink.ui.control.SimulationProgress
        ImageDisp              matlab.ui.control.Image
        Credit                 matlab.ui.control.Hyperlink
        ExportData_Button      matlab.ui.control.Button
        FeatureDropDown        matlab.ui.control.DropDown
        FeatureLabel           matlab.ui.control.Label
        Area_Field             matlab.ui.control.NumericEditField
        Area_Text              matlab.ui.control.Label
        Number_of_Cells_Field  matlab.ui.control.NumericEditField
        Number_of_Cells_Text   matlab.ui.control.Label
        Segment_Button         matlab.ui.control.Button
        Help_Button            matlab.ui.control.Button
        Settings_Button        matlab.ui.control.Button
        UploadImagesButton     matlab.ui.control.Button
        HistogramAxes          matlab.ui.control.UIAxes
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    properties (Access = private)
    Images cell = {}; % Stores the uploaded image
    CurrentImageIndex double = 1;
    SegmentedImages cell = {}; % Stores the segmented image
    net; % Stores the pre-trained CNN network
    Masks cell = {};
    Boxes cell = {};
    Labels cell = {};
    Scores cell = {};
    AnalysisData cell = {};
    end
    
    methods (Access = private)
        
        function [] = fixIMGDisplay(app, Image, ImageDisp) %function to display image file without labels properly
            %title(ImageAxes, []);
            %xlabel(ImageAxes, []);
            %ylabel(ImageAxes, []);
            %ImageAxes.XAxis.TickLabels = {};
            %ImageAxes.YAxis.TickLabels = {};
            %ImageAxes.PositionConstraint="outerposition";
            %ImageAxes.InnerPosition=ImageAxes.OuterPosition
    %         I = imshow(Image, 'Parent', app.ImageAxes, ...
    % 'XData', [1 app.ImageAxes.Position(3)], ...
    % 'YData', [1 app.ImageAxes.Position(4)]);
    %         box(ImageAxes,"on")
        if size(Image, 3)==1
            ImageDisp.ImageSource = repmat(Image, [1 1 3]);
        elseif size(Image, 3)==3
            ImageDisp.ImageSource = Image;
        end
       
        end
        function updateDisplayedImage(app)
            if isempty(app.SegmentedImages)
                return;
            end
    
            fixIMGDisplay(app, app.SegmentedImages{app.CurrentImageIndex}, app.ImageDisp);
            data = app.AnalysisData{app.CurrentImageIndex};
            app.Number_of_Cells_Field.Value = size(data, 1);
            app.Area_Field.Value = mean(data.Area);
            app.updatePlot();
        end


        function updatePlot(app)
       % Plotting depending on toggles
            if isempty(app.statsPerImage)
                return;
            end

            % Gather stats
            if  strcmp(app.ToggleCumulative.Value, 'On')
                % Cumulative across all images
                combinedStats = vertcat(app.statsPerImage{:});
            else
                % Just show first image's stats
                combinedStats = app.statsPerImage{1};
            end

            % Decide plot type
            if  strcmp(app.TogglePlotType.Value, 'Off')
                % Histogram view
                featureName = app.DropdownX.Value;
                featureValues = [combinedStats.(featureName)];
                histogram(app.HistogramAxes, featureValues);
                xlabel(app.HistogramAxes, featureName);
                ylabel(app.HistogramAxes, 'Frequency');
                title(app.HistogramAxes, ['Histogram of ', featureName]);
            else
                % Scatter plot view
                featureX = app.DropdownX.Value;
                featureY = app.DropdownY.Value;

                xValues = [combinedStats.(featureX)];
                yValues = [combinedStats.(featureY)];

                scatter(app.HistogramAxes, xValues, yValues, 'filled');
                xlabel(app.HistogramAxes, featureX);
                ylabel(app.HistogramAxes, featureY);
                title(app.HistogramAxes, [featureY, ' vs ', featureX]);
            end
        end
    
    
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            fixIMGDisplay(app, ones(520, 740)*0.9, app.ImageDisp);
        end

        % Callback function: ImageDisp, UploadImagesButton
        function UploadImage(app, event)
            [files, path] = uigetfile({'*.png;*.jpg;*.tif'}, 'Select Images', 'MultiSelect', 'on');
            if isequal(files, 0)
                errordlg('No images selected!', 'Error');
                return;
            end

            if ~iscell(files)
                files = {files};
            end

            app.Images = {};
            for k = 1:length(files)
                img = rescale(imread(fullfile(path, files{k})));
                if size(img, 3) == 3
                    img = mean(img, 3);
                end
                app.Images{end+1} = img;
            end

            app.CurrentImageIndex = 1;
            app.SegmentedImages = {};
            app.Masks = {};
            app.Boxes = {};
            app.Labels = {};
            app.Scores = {};
            app.AnalysisData = {};
            fixIMGDisplay(app, app.Images{1}, app.ImageDisp);
        end
        
        end

        % Button pushed function: Segment_Button
        function Segment(app, event)
        if isempty(app.Images)
        errordlg('Please upload an image first!', 'Error');
        return;
        end

    % Add MRCNNsrc path (Make sure this path is correct)
    addpath(genpath('CNNTraining'));

    % Load pre-trained CNN model if not already loaded
    if isempty(app.net)
        try
            app.net = load('ResN101.mat', 'net'); % Load the network file
            app.net = app.net.net; % Extract the network object
            app.net.OverlapThreshold = 0.1;
        catch
            errordlg('Error loading CNN model. Check file path!', 'Error');
            return;
        end
    end

    % Perform cell segmentation
    try
        app.ProgressIndicator.Value = 0.1;
        pause(0.1);
        [app.Masks app.Labels app.Scores app.Boxes] = segmentCells(app.net, app.Image, "NumstrongestRegions",Inf, MinSize=[4 4], MaxSize=[80 80],SelectStrongest=true, SegmentThreshold=0.1);
        app.ProgressIndicator.Value = 0.7;
                pause(0.1);

        if (isempty(app.Masks))
            app.SegmentedImages = app.Image;
        else
            app.SegmentedImages = insertObjectMask(app.Image, app.Masks,Color=lines(size(app.Masks, 3)) );
        end
        % Display the segmented image with masks
        fixIMGDisplay(app, app.SegmentedImages, app.ImageDisp);
        
        app.AnalysisData = ExtractFeatures(app.Image, app.Masks, app.Boxes, 1);

        % Update the cell count field
        app.Number_of_Cells_Field.Value = size(app.AnalysisData, 1);
        app.Area_Field.Value = mean(app.AnalysisData.Area);
        app.ProgressIndicator.Value = 1.0;
                pause(0.2);
    catch ME
        errordlg(['Segmentation error: ', ME.message], 'Error');
    end
        PlotHistogram(app,1);

        
        end

        % Value changed function: FeatureDropDown
        function PlotHistogram(app, event)
            value = app.FeatureDropDown.Value;
            if isempty(app.AnalysisData)
                return;
            end
            % Extract the selected feature's values
            featureData = app.AnalysisData.(value);
            
            % Plot histogram
            histogram(featureData, 'Parent',app.HistogramAxes);
            xlabel(app.HistogramAxes, value);
            ylabel(app.HistogramAxes, 'Frequency');

        end

        % Button pushed function: PreviousButton
        function PreviousButtonPushed(app, event)
              if app.CurrentImageIndex > 1
                app.CurrentImageIndex = app.CurrentImageIndex - 1;
                app.updateDisplayedImage();
            end
        
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
           if app.CurrentImageIndex < length(app.SegmentedImages)
                app.CurrentImageIndex = app.CurrentImageIndex + 1;
                app.updateDisplayedImage();
            end
        
        end

        % Value changed function: ToggleSwitch
        function ToggleSwitchValueChanged(app, event)
            value = app.ToggleSwitch.Value;
            app.updatePlot();

        end

        % Value changed function: XAxisDropdown
        function XAxisDropdownValueChanged(app, event)
            value = app.XAxisDropdown.Value;
            app.updatePlot();
      
        end

        % Value changed function: YAxisDropdown
        function YAxisDropdownValueChanged(app, event)
            value = app.YAxisDropdown.Value;
            app.updatePlot();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            colormap(app.UIFigure, 'turbo');
            app.UIFigure.Name = 'MATLAB App';
            app.HistogramAxes = uiaxes(app.UIFigure);
            ylabel(app.HistogramAxes, 'Frequency')
            colormap(app.HistogramAxes, 'turbo')
            app.UploadImagesButton = uibutton(app.UIFigure, 'push');
            app.UploadImagesButton.ButtonPushedFcn = createCallbackFcn(app, @UploadImage, true);
            app.UploadImagesButton.Text = 'Select Image';
            app.Settings_Button = uibutton(app.UIFigure, 'push');
            app.Settings_Button.Text = 'Advanced Settings';
            app.Settings_Button.Icon = fullfile(pathToMLAPP, '3524659.png');
            app.Help_Button = uibutton(app.UIFigure, 'push');
            app.Help_Button.Text = '?';
            app.Segment_Button = uibutton(app.UIFigure, 'push');
            app.Segment_Button.ButtonPushedFcn = createCallbackFcn(app, @Segment, true);
            app.Segment_Button.Text = 'Segment';
            app.Number_of_Cells_Text = uilabel(app.UIFigure);
            app.Number_of_Cells_Text.Text = 'Number of Cells in Image(s):';
            app.Number_of_Cells_Field = uieditfield(app.UIFigure, 'numeric');
            app.Area_Text = uilabel(app.UIFigure);
            app.Area_Text.Text = 'Average Area (px^2):';
            app.Area_Field = uieditfield(app.UIFigure, 'numeric');
            app.FeatureLabel = uilabel(app.UIFigure);
            app.FeatureLabel.Text = 'Feature:';
            app.FeatureDropDown = uidropdown(app.UIFigure);
            app.FeatureDropDown.Items = {'Area', 'Perimeter', 'STDeviation', 'AspectRatio', 'Eccentricity', 'Circularity', 'Solidity', 'MaxDiameter', 'Kurtosis'};
            app.FeatureDropDown.ValueChangedFcn = createCallbackFcn(app, @PlotHistogram, true);
            app.FeatureDropDown.Value = 'Area';
            app.ExportData_Button = uibutton(app.UIFigure, 'push');
            app.ExportData_Button.Text = 'Export Data';
            app.Credit = uihyperlink(app.UIFigure);
            app.Credit.URL = 'https://github.com/TechAvi-eng/4th-Year-Image-Processing-Project';
            app.Credit.Text = 'As Yet Unnamed v0.2';
            app.ImageDisp = uiimage(app.UIFigure);
            app.ImageDisp.ImageClickedFcn = createCallbackFcn(app, @UploadImage, true);
            app.SimulationProgress = uisimprogress(app.UIFigure);
            app.SimulationProgress.ShowSimTime = 'off';
            app.SimulationProgress.ShowElapsedTime = 'off';
            app.ModeSwitch = uiswitch(app.UIFigure, 'slider');
            app.ModeSwitch.Items = {'All Images', 'Individual Images'};
            app.ModeSwitch.Value = 'All Images';
            app.PreviousButton = uibutton(app.UIFigure, 'push');
            app.PreviousButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousButtonPushed, true);
            app.PreviousButton.Text = 'Previous';
            app.NextButton = uibutton(app.UIFigure, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.ToggleSwitch = uiswitch(app.UIFigure, 'slider');
            app.ToggleSwitch.Items = {'Histogram', 'Scatter'};
            app.ToggleSwitch.ValueChangedFcn = createCallbackFcn(app, @ToggleSwitchValueChanged, true);
            app.ToggleSwitch.Value = 'Histogram';
            app.YAxisLabel = uilabel(app.UIFigure);
            app.YAxisLabel.Text = 'Y Axis';
            app.YAxisDropdown = uidropdown(app.UIFigure);
            app.YAxisDropdown.Items = {'Area', 'Perimeter', 'STDeviation', 'AspectRatio', 'Eccentricity', 'Circularity', 'Solidity', 'MaxDiameter', 'Kurtosis'};
            app.YAxisDropdown.ValueChangedFcn = createCallbackFcn(app, @YAxisDropdownValueChanged, true);
            app.YAxisDropdown.Value = 'Area';
            app.YAxisLabel_2 = uilabel(app.UIFigure);
            app.YAxisLabel_2.Text = 'X Axis';
            app.XAxisDropdown = uidropdown(app.UIFigure);
            app.XAxisDropdown.Items = {'Area', 'Perimeter', 'STDeviation', 'AspectRatio', 'Eccentricity', 'Circularity', 'Solidity', 'MaxDiameter', 'Kurtosis'};
            app.XAxisDropdown.ValueChangedFcn = createCallbackFcn(app, @XAxisDropdownValueChanged, true);
            app.XAxisDropdown.Value = 'Area';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end